classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        BasisEinstellungenTab       matlab.ui.container.Tab
        KostenfrStrombezugLabel     matlab.ui.control.Label
        Label_C_Consumption         matlab.ui.control.Label
        EEGVergtungLabel            matlab.ui.control.Label
        Label_C_FeedIn              matlab.ui.control.Label
        Label_C_Diff                matlab.ui.control.Label
        DifferenzLabel              matlab.ui.control.Label
        StromkosteninkWhLabel       matlab.ui.control.Label
        Slider_C_var                matlab.ui.control.Slider
        installiertePVLeistunginkWLabel  matlab.ui.control.Label
        Slider_PVSize               matlab.ui.control.Slider
        BatteriekapazittinkWhLabel  matlab.ui.control.Label
        Slider_BatCap               matlab.ui.control.Slider
        LastinkWhLabel              matlab.ui.control.Label
        Slider_Load                 matlab.ui.control.Slider
        FixkosteninLabel            matlab.ui.control.Label
        Slider_C_fix                matlab.ui.control.Slider
        EEGVergtunginkWhLabel       matlab.ui.control.Label
        DropDown_C_EEG              matlab.ui.control.DropDown
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        VPPEinstellungenTab         matlab.ui.container.Tab
        GrafikenTab                 matlab.ui.container.Tab
        Graphics_Panel              matlab.ui.container.Panel
        UpdateButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        ERG;     % struct with simulation results
        PVSize;
        BatCap;
        Load;
        C_var;
        C_fix;
        C_EEG;
        
        C_FeedIn;
        C_Consumption;
        C_Diff;
        
        idx_PVSize=1;
        idx_BatCap=1;
        idx_Load=1;
        idx_C_var=1;
        idx_C_fix=1;
        idx_C_EEG = 1;
        
        % test
        pax;
        barplot_cost;
        xax;
    end
    
    
    methods (Access = private)
        function update_Plots(app)
            Data=[app.C_FeedIn -app.C_Consumption app.C_Diff];
            app.barplot_cost = bar(app.pax,app.xax, Data, "FaceColor","flat");
            app.barplot_cost.CData=[0.26 0.26 0.26 ; 0 0.8 0; 0.93 0.79 0];
        end
        
        
        function update_C_Consumption(app)
            app.C_Consumption = round(app.ERG.C_Consumption(app.idx_PVSize,app.idx_BatCap,app.idx_Load,app.idx_C_var,app.idx_C_fix),2);
            app.C_Diff = app.C_FeedIn - app.C_Consumption;
            app.Label_C_Consumption.Text = num2str(app.C_Consumption);
            app.Label_C_Diff.Text = num2str(app.C_Diff);
            app.update_Plots();
        end
        
        function update_C_FeedIn(app)
            app.C_FeedIn = round(app.ERG.C_FeedIn(app.idx_PVSize,app.idx_BatCap,app.idx_Load,app.idx_C_EEG),2);
            app.C_Diff = app.C_FeedIn - app.C_Consumption;
            app.Label_C_FeedIn.Text = num2str(app.C_FeedIn);
            app.Label_C_Diff.Text = num2str(app.C_Diff);
            app.update_Plots();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ERG = evalin("base","ERG");
            app.PVSize = app.ERG.PVSize;
            app.BatCap = app.ERG.BatCap;
            app.Load = app.ERG.Load;
            app.C_var = app.ERG.C_var;
            app.C_fix = app.ERG.C_fix;
            app.C_EEG = app.ERG.C_EEG;
            
            
            % Customize Sliders
            % need solution for MajorTicks property
            % PVSize Slider
            app.Slider_PVSize.Limits = [min(app.PVSize),max(app.PVSize)];
            app.Slider_PVSize.MinorTicks = app.PVSize;
            app.Slider_PVSize.Value = min(app.PVSize);
            app.Slider_PVSize.MajorTicks = [app.PVSize(1:end)];
            % BatCap Slider
            app.Slider_BatCap.Limits = [min(app.BatCap),max(app.BatCap)];
            app.Slider_BatCap.MinorTicks = app.BatCap;
            app.Slider_BatCap.Value = min(app.BatCap);
            app.Slider_BatCap.MajorTicks = [app.BatCap(1:end)];
            % Load Slider
            app.Slider_Load.Limits = [min(app.Load),max(app.Load)];
            app.Slider_Load.MinorTicks = app.Load;
            app.Slider_Load.Value = min(app.Load);
            app.Slider_Load.MajorTicks = [app.Load(1:2:end-2), app.Load(end)];
            % C_var Slider
            app.Slider_C_var.Limits = [min(app.C_var),max(app.C_var)];
            app.Slider_C_var.MinorTicks = app.C_var;
            app.Slider_C_var.Value = min(app.C_var);
            app.Slider_C_var.MajorTicks = [app.C_var(1:2:end-1), app.C_var(end)];
            % C_fix Slider
            app.Slider_C_fix.Limits = [min(app.C_fix),max(app.C_fix)];
            app.Slider_C_fix.MinorTicks = app.C_fix;
            app.Slider_C_fix.Value = min(app.C_fix);
            app.Slider_C_fix.MajorTicks = [app.C_fix(1:2:end-1), app.C_fix(end)];
            % C_EEG Dropdown
            app.DropDown_C_EEG.Items=string(app.C_EEG);
            
            % Label
            app.C_Consumption =round(app.ERG.C_Consumption(1,1,1,1,1),2);
            app.C_FeedIn =round(app.ERG.C_FeedIn(1,1,1),2);
            app.C_Diff =app.C_FeedIn-app.C_Consumption;
            app.Label_C_Consumption.Text = num2str(app.C_Consumption);
            app.Label_C_FeedIn.Text = num2str(app.C_FeedIn);
            app.Label_C_Diff.Text = num2str(app.C_Diff);


            %% Graphische Darstellungen
            
            t = tiledlayout(app.Graphics_Panel,2,2);
            
            % Costs
            app.xax=categorical({'FeedIn','Consumption','Diff'});
            app.xax = reordercats(app.xax,{'Consumption' 'FeedIn' 'Diff'});
            Data=[app.C_FeedIn -app.C_Consumption app.C_Diff];
            app.pax = nexttile(t);
            app.barplot_cost = bar(app.pax,app.xax, Data, 'FaceColor', "flat");
            app.barplot_cost.CData=[0.26 0.26 0.26 ; 0 0.8 0; 0.93 0.79 0];
            
            % Tile 2
            ax1 = nexttile(t);
            Y = [500, 100, 350];
            X = ["Batterieentladung", "PV-Direktversorgung", "Netzbezug"];
            pie(ax1,Y,X);
            
            % Tile 3
%             ax2 = nexttile(t);
%             [X,Y,Z] = peaks(20);
%             surf(ax1,X,Y,Z);
        end

        % Value changing function: Slider_PVSize
        function Slider_PVSizeValueChanging(app, event)
            %             [val,app.idx_PVSize] = min(abs(app.PVSize-app.Slider_PVSize.Value));
            %             app.Slider_PVSize.Value = app.PVSize(app.idx_PVSize);
        end

        % Value changed function: Slider_PVSize
        function Slider_PVSizeValueChanged(app, event)
            [val,app.idx_PVSize] = min(abs(app.PVSize-app.Slider_PVSize.Value));
            app.Slider_PVSize.Value = app.PVSize(app.idx_PVSize);
            app.update_C_Consumption();
            app.update_C_FeedIn();
        end

        % Value changed function: Slider_BatCap
        function Slider_BatCapValueChanged(app, event)
            [val,app.idx_BatCap] = min(abs(app.BatCap-app.Slider_BatCap.Value));
            app.Slider_BatCap.Value = app.BatCap(app.idx_BatCap);
            app.update_C_Consumption();
            app.update_C_FeedIn();
        end

        % Value changed function: Slider_Load
        function Slider_LoadValueChanged(app, event)
            [val,app.idx_Load] = min(abs(app.Load-app.Slider_Load.Value));
            app.Slider_Load.Value = app.Load(app.idx_Load);
            app.update_C_Consumption();
            app.update_C_FeedIn();
        end

        % Value changed function: Slider_C_fix
        function Slider_C_fixValueChanged(app, event)
            [val,app.idx_C_fix] = min(abs(app.C_fix-app.Slider_C_fix.Value));
            app.Slider_C_fix.Value = app.C_fix(app.idx_C_fix);
            app.update_C_Consumption();
        end

        % Value changed function: Slider_C_var
        function Slider_C_varValueChanged(app, event)
            [val,app.idx_C_var] = min(abs(app.C_var-app.Slider_C_var.Value));
            app.Slider_C_var.Value = app.C_var(app.idx_C_var);
            app.update_C_Consumption();
        end

        % Callback function
        function Slider_C_EEGValueChanged(app, event)
            [val,app.idx_C_EEG] = min(abs(app.C_EEG-app.Slider_C_EEG.Value));
            app.Slider_C_EEG.Value = app.C_EEG(app.idx_C_EEG);
            app.update_C_Consumption();
            app.update_C_FeedIn();
        end

        % Value changed function: DropDown_C_EEG
        function DropDown_C_EEGValueChanged(app, event)
            [val, app.idx_C_EEG] = min(abs(app.C_EEG-str2double(app.DropDown_C_EEG.Value)));
            app.update_C_Consumption();
            app.update_C_FeedIn();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasisEinstellungenTab = uitab(app.TabGroup);
            app.BasisEinstellungenTab.Title = 'Basis Einstellungen';
            app.KostenfrStrombezugLabel = uilabel(app.BasisEinstellungenTab);
            app.KostenfrStrombezugLabel.Text = 'Kosten für Strombezug:';
            app.Label_C_Consumption = uilabel(app.BasisEinstellungenTab);
            app.EEGVergtungLabel = uilabel(app.BasisEinstellungenTab);
            app.EEGVergtungLabel.Text = 'EEG Vergütung:';
            app.Label_C_FeedIn = uilabel(app.BasisEinstellungenTab);
            app.Label_C_Diff = uilabel(app.BasisEinstellungenTab);
            app.DifferenzLabel = uilabel(app.BasisEinstellungenTab);
            app.DifferenzLabel.Text = 'Differenz:';
            app.StromkosteninkWhLabel = uilabel(app.BasisEinstellungenTab);
            app.StromkosteninkWhLabel.Text = 'Stromkosten in €/kWh';
            app.Slider_C_var = uislider(app.BasisEinstellungenTab);
            app.Slider_C_var.ValueChangedFcn = createCallbackFcn(app, @Slider_C_varValueChanged, true);
            app.installiertePVLeistunginkWLabel = uilabel(app.BasisEinstellungenTab);
            app.installiertePVLeistunginkWLabel.Text = 'installierte PV Leistung in kW';
            app.Slider_PVSize = uislider(app.BasisEinstellungenTab);
            app.Slider_PVSize.ValueChangedFcn = createCallbackFcn(app, @Slider_PVSizeValueChanged, true);
            app.Slider_PVSize.ValueChangingFcn = createCallbackFcn(app, @Slider_PVSizeValueChanging, true);
            app.BatteriekapazittinkWhLabel = uilabel(app.BasisEinstellungenTab);
            app.BatteriekapazittinkWhLabel.Text = 'Batteriekapazität in kWh';
            app.Slider_BatCap = uislider(app.BasisEinstellungenTab);
            app.Slider_BatCap.ValueChangedFcn = createCallbackFcn(app, @Slider_BatCapValueChanged, true);
            app.LastinkWhLabel = uilabel(app.BasisEinstellungenTab);
            app.LastinkWhLabel.Text = 'Last in kWh';
            app.Slider_Load = uislider(app.BasisEinstellungenTab);
            app.Slider_Load.ValueChangedFcn = createCallbackFcn(app, @Slider_LoadValueChanged, true);
            app.FixkosteninLabel = uilabel(app.BasisEinstellungenTab);
            app.FixkosteninLabel.Text = 'Fixkosten in €';
            app.Slider_C_fix = uislider(app.BasisEinstellungenTab);
            app.Slider_C_fix.ValueChangedFcn = createCallbackFcn(app, @Slider_C_fixValueChanged, true);
            app.EEGVergtunginkWhLabel = uilabel(app.BasisEinstellungenTab);
            app.EEGVergtunginkWhLabel.Text = 'EEG Vergütung in €/kWh:';
            app.DropDown_C_EEG = uidropdown(app.BasisEinstellungenTab);
            app.DropDown_C_EEG.ValueChangedFcn = createCallbackFcn(app, @DropDown_C_EEGValueChanged, true);
            app.Label = uilabel(app.BasisEinstellungenTab);
            app.Label.Text = '€';
            app.Label_2 = uilabel(app.BasisEinstellungenTab);
            app.Label_2.Text = '€';
            app.Label_3 = uilabel(app.BasisEinstellungenTab);
            app.Label_3.Text = '€';
            app.VPPEinstellungenTab = uitab(app.TabGroup);
            app.VPPEinstellungenTab.Title = 'VPP Einstellungen';
            app.GrafikenTab = uitab(app.TabGroup);
            app.GrafikenTab.Title = 'Grafiken';
            app.Graphics_Panel = uipanel(app.GrafikenTab);
            app.Graphics_Panel.Title = 'Graphics_Panel';
            app.UpdateButton = uibutton(app.GrafikenTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end